classdef TaucPlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        CenterPanel  matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = private)

        function results = residuals(app,params,xdata,ydata)
        % Division by ydata is to ensure that errors at low absorbance values are given high importance and the ones
        % at higher absorbance values are given lower importance (since the important region is where the absorbance starts increasing
        % from low value prior to band gap to higher values...The noise at very high energy levels usually having high absorbance will not 
        % effect the quality of the result a lot)
            results = sum((abs(ydata-linpiecewise(app,params,xdata))./ydata).^2);
        end


        function fitdata = linpiecewise(~, params,x)
        % params: zeroth index corresponds to the yfit value at x=x0. Next num_knots indices correspond to the knot positions. Next num_knots-1 indices correspond
        % to slopes for the linear regions.  Piecewise linear addition to obtain the value of fitdata at a specific value of x. 
            fitdata = ones(size(x))*params(1);
            num_knots = length(params)/2;

            for i=2:num_knots
                fitdata = fitdata + params(num_knots+i)*(x>=params(i)).*(min([ones(size(x))*params(i+1),x],[],2) - params(i));
            end
        end

        function res = linearfit(app,xdata, ydata, num_knots, min_knotdist,xorder,options,tol)
        % xorder: if loc in data represents smaller value of energy to higher value of energy then xorder is increasing otherwise decreasing
        % first constraint in cons is to ensure minimum knot distance min_knotdist. Second equality constraint indicates that the first and the last knot are fixed to the first and last values of x
        % Initially knot locations are evenly spread out between xvalues of interest and all slopes are set to zero. tolerance is the tolerance for convergence of linear properties.
            if xorder == "increasing"
                sign = 1;
            else
                sign = -1;
            end

            locs = 1:num_knots;
            ones = struct('type','ineq','fun',@(params) params(locs(1:end)+1)*sign-(params(locs(1:end))*sign+min_knotdist));
            twos = struct('type','ineq','fun',@(params) [params(2);params(num_knots)]-[xdata(1);xdata(end)]);
            cons = [ones,twos];

            init_x = xdata(1):(xdata(end)-xdata(1))/(num_knots-1):xdata(end); % SHOULD num_knots BE -1 HERE? I think so
            if numel(init_x)~=num_knots
                init_x = [init_x,xdata(end)];
            else
                init_x(end)=xdata(end);
            end

            for i = 1:numel(init_x)
                [~,init_idxs(:,i)] = min(abs(xdata-init_x(i)));
            end

            for i = 1:length(init_idxs)-1
                init_slopes(:,i) = (ydata(init_idxs(i+1))-ydata(init_idxs(i)))/(xdata(init_idxs(i+1))-xdata(init_idxs(i)));
            end

            init_params = [ydata(1),init_x,init_slopes];
%                         res = sp.optimize.minimize(residuals(app),init_params, args=(xdata,ydata),constraints=cons, method='SLSQP',options=options,tol=tol);
                        A = [];
                        b = [];
            %             Aeq = [];
            %             beq = [];
            %             nlcon = [];

%             res = fmincon(residuals(app,init_params,xdata,ydata),init_params,A,b,Aeq,beq);
%             params = [params(1),knots,slopes];

            [coefficients,resnorm] = lsqcurvefit(@(x,params) app.MultiVoigtPeak(x,params),...
                InitialGuess,app.FitBE(XDATA),FitIntensity(YDATA),LowerBound,UpperBound,options);
            res = fmincon(residuals(app,init_params,xdata,ydata),init_x,cons);
        end

        function params = mergelinsegs(~, params, num_knots,max_merge_differentialTP,merge_linsegslopediff_percent)
            knots = params(2:num_knots);
            slopes = params(num_knots+2:(2*num_knots)+1);

            if merge_linsegslopediff_percent >= 1
                merge_linsegslopediff_percent = merge_linsegslopediff_percent/100;
            end

            while true
                num_merges = 1;
                j = 1;
                loc = 1;

                newslopes = magic(size(slopes,1));
                newknots = magic(size(knots,1));

                newslopes(1) = slopes(1);
                newknots(1:3) = knots(1:3);

                while loc < size(slopes,1) - 1
                    medianslope = (newslopes(j)*(newknots(j+1) - newknots(j)) + slopes(loc+1)*(knots(loc+2) - knots(loc+1)))/(knots(loc+2)-newknots(j));
                    differentialTPdiff = (medianslope - newslope(j))*(newknots(j+1)-newknots(j));
                    TPdiff = newslopes(j)*(newknots(j+1)-newknots(j));
                    TPdiffn = slopes(loc+1)*(knots(loc+2)-knots(loc+1));
                    if abs(differentialTPdiff) < min(max_merge_differentialTP,merge_linsegslopediff_percent*(TPdiff+TPdiffn))
                        newslopes(j) = medianslope;
                        slopes(loc) = medianslope;
                        slopes(loc+1) = medianslope;
                        newknots(j+1) = knots(loc+2);
                        loc = loc+1;
                        num_merges = num_merges + 1;
                    else
                        j = j+1;
                        loc = loc+1;
                        newslopes(j) = slopes(loc);
                        newknots(j) = knots(loc);
                        newknots(j+1) = knots(loc+1);
                    end
                end
                newslopes(j+1:end) = []; % newslopes=np.delete(newslopes,np.s_[j+1::],axis=None)
                newknots(j+2:end) = []; % newknots=np.delete(newknots,np.s_[j+2::],axis=None)
                slopes = newslopes;
                knots = newknots;

                if num_merges == 0
                    break
                end
            end

            num_knots = j+2;
            params = [params(1),knots,slopes];
        end

        function [linfitd, fomd] = fitresult(app, data, bgtyp, max_numbgs, num_knots, tol, min_allowedslope, min_bgTP_diff, min_bkgrdslope, ...
                min_bgbkgrdslopediff, min_finseglength, min_bgTP_finseg_diff, min_bgfinalseglength, max_merge_differentialTP, merge_linsegslopediff_percent, ...
                maxtol, min_knotdist, xorder, dispresult)
        % This function creates data for each bg type and interacts with fitting, residual measurement functions to identify the best linear piecewise parameters which are then sent to calc_bandgap for bandgap calculations.
            % Default values
            % min_knotdist = 0.05;
            % xorder = 'increasing';
            % dispresult = false;

            linfitd = containers.Map;

            datarray = table2array(data(bgtyp{1,1}));
            if min(datarray) < 0.03
                yoffset=-min(datarray)+0.03;
            else
                yoffset = 0;
            end

            for maxiter = [1000,2000]
                res = linearfit(app,table2array(data('hv')),datarray+yoffset,num_knots,min_knotdist,xorder,containers.Map({'maxiter', 'disp'},[maxiter,dispresult]),tol); % This map was originall {"Inside stuff here"}
                if res.success
                    break
                end
            end

            if ~res.success
                maxiter=2000;
                for i= log10(tol):log10(maxtol)+1
                    newtol = 10^i;
                    res = linearfit(app,data('hv'),datarray+yoffset,num_knots,min_knotdist,xorder,containers.Map({'maxiter', 'disp'},[maxiter,dispresult]),newtol); % This map was originall {"Inside stuff here"}
                    if res.success
                        break
                    end
                end

                if ~res.success
                    linfitd = containers.Map;
                    fomd = containers.Map('bgcode_0',9);
                else
                    tempparams = res.x;
                    tempparams(1) = tempparams(1) - yoffset;
                    tempparams = mergelinsegs(app,tempparams,num_knots,max_merge_differentialTP,merge_linsegslopediff_percent);
                    [linfitd,fomd] = calc_bandgap(app,tempparams,np.size(tempparams)/2,max_numbgs,min_allowedslope, min_bgTP_diff,min_bkgrdslope,min_bgbkgrdslopediff,min_finseglength, min_bgTP_finseg_diff,min_bgfinalseglength,bgtyp);
                    data([bgtyp{1,1},'_linfit']) = linpiecewise(app,[linfitd('y0'),linfitd('knots'),linfitd('slopes')],data('hv'));
                end
            end
        end

        function [linfitd,fomd] = calc_bandgap(params,num_knots,max_numbgs,min_allowedslope,min_bgTP_diff,min_bkgrdslope,min_bgbkgrdslopediff,min_finseglength,min_bgTP_finseg_diff ,min_bgfinalseglength,bgtyp)
            knots = params(2:num_knots+2);
            slopes = params(num_knots+2:2*num_knots);
            num_slopes = size(slopes,1);
            bgknots_lower = [];
            bkgrdknots_lower = [];
            bg = [];
            abs_expl = [];
            bgcode = [];

            if (knots(num_slopes) - knots(num_slopes-1)) >= min_finseglength
                num_segments = num_slopes;
            else
                num_segments = num_slopes - 1;
            end

            tot_segs = num_segments;

            for i=1:tot_segs %maybe tot_segs - 1?
                if slopes(i) < min_allowedslope
                    num_segments = i;
                    break
                end
            end

            if num_segments == tot_segs
                for i = 1:sum_segments % maybe 2:sum_segments-1?
                    if slopes(i) > slopes(i-1) && slopes(i) > 0 && slopes(i-1) > min_bkgrdslope
                        TPdiff = (knots(i+1) - knots(i))*slopes(i);

                        if TPdiff >= min_bgTP_diff
                            if slopes(i) > slopes(i+1)
                                bgknots_lower(end+1) = i;
                                abs_expl(end + 1) = TPdiff;
                                bgcode(end+1) = 0; %% is end+1 the right way to do this?
                            end
                        end
                    end
                end

                if numel(bgknots_lower) == 0
                    j = num_segments-1;

                    if slopes(j) > slopes(j-1) && slopes(j) > 0 && slopes(j-1) > min_bkgrdslope
                        TPdiff = (knots(j+1) - knots(j))*slopes(j);
                        if TPdiff >= min_bgTP_finseg_diff && knots(j+1) - knots(j) > min_bgfinalseglength
                            bgknots_lower(end+1) = i;
                            abs_expl(end + 1) = TPdiff;
                            bgcode(end+1) = 6; %% is end+1 the right way to do this?
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    for i=1:size(bgknots_lower,1)
                        if i==0
                            low_limit = -1;
                        else
                            low_limit = bgknots_lower(i-1);
                        end

                        if bgknots_lower(i) - 1 == low_limit
                            delete(bgknots_lower(i:end));
                            delete(abs_expl(i:end));
                            delete(bgcode(i:end));
                            break;
                        end
                        % A band gap segments bkgrnd segment cannot be previous band gap segment
                        for loc = bgknots_lower(i)-1:-1:low_limit
                            if slopes(loc) < slopes(loc+1) && slopes(loc) > min_bkgrdslope
                                if loc == low_limit + 1
                                    bkgrdknots_lower(end+1) = loc; %python.extend func
                                    % This block gets executed when the bkgrd segment is just to the right of the previous band gap segment
                                    break
                                else
                                    continue
                                end
                            elseif loc ~= bgknots_lower(i) - 1
                                bkgrdknots_lower(end+1) = loc + 1;
                                break
                            else
                                temp = size(bgknots_lower,1);
                                delete(bgknots_lower(i:end));
                                delete(abs_expl(i:end));
                                delete(bgcode(i:end));

                                i = temp;

                                %This block should never get executed because any bgknot will have corresponding bkgrdknot except
                                %when the previous segment is also a band gap segment taken care of bgknots_lower[i]-1==low_limit block above
                            end
                        end
                    end
                end

                if numel(bgknots_lower) ~= 0
                    if ~(numel(bgcode) == 1 && bgcode(1) == 1)
                        bgcode_array = [bgcode];
                        if ~(((numel(abs_expl) == numel(bgknots_lower) && (numel(bgknots_lower) == numel(bkgrdknots_lower))) && numel(bgcode_array(bgcode ~= 1)) == numel(bkgrdknots_lower)))
                            error('abs_expl,bgknots_lower,bgcodes and bkgrdknots_lower do not have the same size');
                        end
                    end

                    for i = 1:size(bkgrdknots_lower,1)
                        if (slope(bgknots_lower(i)) - slopes(bkgrdknots_lower(i))) < min_bgbkgrdslopediff
                            first = ones(3,size(bgknots_lower,1));
                            second = ones(3,size(bkgrdknots_lower,1));
                            third = ones(3,size(abs_expl,1));
                            
                            bgknots_lower(i) = -1000*(first); % BAD FUNCTION CALL FOR ONES
                            bkgrdknots_lower(i) = -1000*(second);
                            abs_expl(i) = -1000*(third);
                        end
                    end
                    bgknots_lower = bgknots_lower(bgknots_lower ~= -1000);
                    bkgrdknots_lower = bkgrdknots_lower(bkgrdknots_lower ~= -1000);
                    abs_expl_lower = abs_expl_lower(abs_expl_lower ~= -1000); % IS THIS THE RIGHT LOGICAL INDEXING? PY SCRIPT USES LAMBDA/ANON FUNCTINOS

                    if numel(bgknots_lower) == 0
                        bgknots_lower = ones(4,1)*NaN;
                        bkgrdknots_lower = ones(4,1)*NaN;
                        bg = ones(4,1)*NaN;
                        abs_expl = ones(4,1)*NaN;

                        bgcode(end+1) = 5;
                    else
                        for i = 1:size(bgknots_lower,1)
                            [y1,y2] = linpiecewise(params,[knots(bgknots_lower,i),knots(bkgrdknots_lower,i)]);

                            m1 = slopes(bgknots_lower(i));
                            m2 = slopes(bkgrdknots_lower(i));

                            x1 = knots(bgknots_lower(i));
                            x2 = knots(bkgrdknots_lower(i));

                            bg(end+1) = (y1-y2-(m1*x1-m2*x2))/(m2-m1);
                        end
                    end

                else
                    bgknots_lower = ones(4,1)*NaN;
                    bkgrdknots_lower = ones(4,1)*NaN;
                    bg = ones(4,1)*NaN;
                    abs_expl = ones(4,1)*NaN;
                    bgcode(end+1) = 3;
                end

            elseif num_segments ~= tot_segs
                if numel(bgcode) == 0
                    bgknots_lower = ones(4,1)*NaN;
                    bkgrdknots_lower = ones(4,1)*NaN;
                    bg = ones(4,1)*NaN;
                    abs_expl = ones(4,1)*NaN;
                    bgcode(end+1) = 1;
                end
            end

            for idx = min(length(bg)):max_numbgs-1
                for lstk = ['bgknots_lower','bkgrdknots_lower', 'bg','abs_expl','bgcode']
                    keys = [lstk,'_',num2str(idx)];
                    vals = eval(lstk(idx));

                    fomd = containers.Map(keys,vals);
                end
            end
            
            for idx = min(length(bg)):max_numbgs-1
                for lstk = ['bgknots_lower','bkgrdknots_lower']
                    keys = [lstk,'_',num2str(idx)];
                    vals = eval(lstk(idx));

                    linfitd = containers.Map(keys,vals);
                end
            end
            linfitd('knots') = knots;
            linfitd('slopes') = slopes;
            linfitd('y0') = params(1);
            %
            %         linfitd=dict([(lstk+'_'+str(idx),eval(lstk)[idx]) for lstk in ['bgknots_lower', \
            %         'bkgrdknots_lower'] for idx in xrange(min(len(bg),max_numbgs))]+[('knots',knots),\
            %         ('slopes',slopes),('y0',params[0])])

            if ~all(isnan(abs_expl))
                for idx = min(length(bg)):max_numbgs-1
                    if ~isnan(fomd('abs_expl_'+str(idx)))
                        x = max(fomd(['abs_expl',str(idx)])); % x=np.argmax([fomd['abs_expl_'+str(idx)] SHOULD IT BE max(fomd,[],2)????
                    end
                end

                fomd('bg_repr') = fomd(['bg_',str(x)]);
                fomd('bgslope_repr') = slopes(bgknots_lower(x));
                fomd('bkgrdslope_repr') = slopes(bkgrdknots_lower(x));
                fomd('bgcode_repr') = fomd(['bgcode_',str(x)]);
            else
                fomd('bg_repr') = nan;
                fomd('bgslope_repr') = nan;
                fomd('bkgrdslope_repr') = nan;
                fomd('bgcode_repr') = nan;
            end

            if length(bg) == 1 && fomd('bgcode_0') == 0
                fomd('bgcode0_only') = fomd('bg_0');
            else
                fomd('bgcode0_only') = nan;
            end
        end

        function logical = identifypeaks(data,typ,abs_minallowedslope,max_allowed_2ndderiv)
            condition = (abs(data(typ+'_2ndderiv')) >= max_allowed_2ndderiv) && (abs(data(typ+'_1stderiv')) <= abs_minallowedslope);
            where = condition(condition>0);
            logical = length(where); %  THIS COULD EASILY BE WRONG
            %return length(logical) % > 0? FIX THIS is it numel??
        end

        function [linfitd,fomd] = runuvvis(app,data,inputvars)
            code0 = 'Successful assignment of bandgap linear segment using simple rules';
            code1 = 'Linear segment with a slope less than min_slope was found';
            code2 = 'Succesful assignment of bandgap linear segment using a slightly higher slope at following segment criterion but bgdiff > min in current segment; exists only the older versions and is obsolete from 3/6/16';
            code3 = 'No linear segment was observed for band gap';
            code4 = 'Band gap linear segment(s) deleted due to inability to identify background linear segment with sufficient difference in slope';
            code5 = 'All Band gap lin segs deleted due to inability to identify background linear segment with sufficient difference in slope';
            code6 = 'Final segment has slope higher than previous and explains bgdiff>min this check occurs only when no band gap has been found with other criteria above';
            % There is a chance that you are underestimating band gaps
            code7 = 'Peaks were found';
            code8 = 'NaNs were found in the absorption spectrum';
            code9 = 'Linear fitting failed';

            pfomd = containers.Map;
            plinfitd = containers.Map;

            for bgtyp = inputvars('analysis_types') % This cycles through analysis types (DA & IA)
                pfomd(bgtyp{1,1})=containers.Map;
                plinfitd(bgtyp{1,1})=containers.Map;

                if any(ismissing(data(bgtyp{1,1})),2) % Check for nan/missing values
                    pfomd(bgtyp{1,1},'bgcode_0') = 8;
                    continue;
                end

                %Implementation of second round of filtering in cases where peaks exist is currently unsupported
                if inputvars('use_absderivs_forpeaks')
                    if identifypeaks(app,data,'abs',inputvars('abs_minallowedslope'),inputvars('max_absolute_2ndderiv'))
                        pfomd(bgtyp,'bgcode_0') = 7; %pfomd[bgtyp]['bgcode_0']=7
                        continue;
                    end
                end

                [plinfitd(bgtyp{1,1}),pfomd(bgtyp{1,1})] = fitresult(app,data,bgtyp,inputvars('maxbgspersmp'),...
                inputvars('num_knots'),inputvars('tol'),inputvars('min_allowedslope'),...
                inputvars('min_bgTP_diff'),inputvars('min_bkgrdslope'),...
                inputvars('min_bgbkgrdslopediff'),inputvars('min_finseglength'),...
                inputvars('min_bgTP_finseg_diff'),...
                inputvars('min_bgfinalseglength'),inputvars('max_merge_differentialTP'),...
                inputvars('merge_linsegslopediff_percent'),inputvars('maxtol'),...
                inputvars('min_knotdist'),'increasing',false);

            end

            for k = keys(pfomd(bgtyp))
                for bgtyp = keys(pfomd)
%                     fomd = containers.Map([bgtyp,'_',k],pfomd(bgtyp,k)); %%%%PYTHON: fomd=dict([(bgtyp+'_'+k,pfomd[bgtyp][k]) for bgtyp in pfomd.keys() for k in pfomd[bgtyp].keys()])
                    fomd([bgtyp,'_',k]) = pfomd(bgtyp,k);
                end
            end

            for k = keys(plinfitd(bgtyp))
                for bgtyp = keys(plinfitd)
%                     linfitd = containers.Map([bgtyp,'_',k],plinfitd(bgtyp,k)); %%%%PYTHON: linfitd=dict([(bgtyp+'_'+k,plinfitd[bgtyp][k]) for bgtyp in plinfitd.keys() for k in plinfitd[bgtyp].keys()])
                    linfitd([bgtyp,'_',k]) = plinfitd(bgtyp,k);
                end
            end

            for idx = 1:length(linfitd(k))
                x = 

                if split(k,'_')
                    for k = keys(linfitd)
                        linfitd = containers.Map(linfitd.items()+[(k+'_'+str(x),linfitd[k][idx])
                end
            %             linfitd=dict(linfitd.items()+[(k+'_'+str(x),linfitd[k][idx]) for k in linfitd.keys() if k.split('_')[-1] in ['knots','slopes'] for idx,x in enumerate(range(len(linfitd[k])))])
            %             [linfitd.pop(k) for k in linfitd.keys() if not np.isscalar(linfitd[k])]

        end
        
        function main(app)
            % Load test data and initialize variables
            load TestData.mat UVVISTEST
            energy = UVVISTEST(:,"E");
            da = UVVISTEST(:,"DA");
            ia = UVVISTEST(:,"IA");

            % Create dictionary equivalents with example data
            keys = {'lower_wl', 'upper_wl', 'num_knots', 'min_knotdist', 'tol', 'maxtol', 'merge_linsegslopediff_percent', 'max_merge_differentialTP', ...
                'min_allowedslope', 'min_finseglength', 'min_bgTP_diff', 'min_bkgrdslope', 'min_bgbkgrdslopediff', 'min_bgTP_finseg_diff', 'min_bgfinalseglength', ...
                'analysis_types', 'maxbgspersmp', 'chkoutput_types', 'abs_minallowedslope', 'max_absolute_2ndderiv', 'use_absderivs_forpeaks'};
            vals = {390, 940, 8, 0.05, 1e-06, 1e-03, 10.0, 10000.0, -2, 0.1, 0.2, -0.05, 0.2, 0.2, 0.2, {'DA','IA'}, 4, {'DA','IA'}, -Inf, Inf, false};
            inputparamsd = containers.Map(keys,vals);

            x = {'hv', 'DA', 'IA'};
            y = {energy, da, ia};
            data = containers.Map(x, y);

            % Run the app
            runuvvis(app,data,inputparamsd);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            main(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TaucPlotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end